classdef blur_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BC_UI                       matlab.ui.Figure
        plot_ax                     matlab.ui.control.UIAxes
        PlotControlPanel            matlab.ui.container.Panel
        XmLabel                     matlab.ui.control.Label
        x_min_tb                    matlab.ui.control.NumericEditField
        EditField_2Label            matlab.ui.control.Label
        x_max_tb                    matlab.ui.control.NumericEditField
        YpxLabel                    matlab.ui.control.Label
        y_min_tb                    matlab.ui.control.NumericEditField
        EditField_2Label_2          matlab.ui.control.Label
        y_max_tb                    matlab.ui.control.NumericEditField
        LensParametersPanel         matlab.ui.container.Panel
        PixelSizemmEditFieldLabel   matlab.ui.control.Label
        px_size_tb                  matlab.ui.control.NumericEditField
        fnumberLabel                matlab.ui.control.Label
        f_num_tb                    matlab.ui.control.NumericEditField
        FocalLengthmmLabel          matlab.ui.control.Label
        focal_length_tb             matlab.ui.control.NumericEditField
        DepthofFieldPanel           matlab.ui.container.Panel
        DNearmEditFieldLabel        matlab.ui.control.Label
        d_near_tb                   matlab.ui.control.NumericEditField
        DFarmEditFieldLabel         matlab.ui.control.Label
        d_far_tb                    matlab.ui.control.NumericEditField
        DoFmEditFieldLabel          matlab.ui.control.Label
        dof_tb                      matlab.ui.control.NumericEditField
        FocusDistance1mSliderLabel  matlab.ui.control.Label
        fd1_sl                      matlab.ui.control.Slider
        FocusDistance2mLabel        matlab.ui.control.Label
        fd2_sl                      matlab.ui.control.Slider
        fd1_tb                      matlab.ui.control.NumericEditField
        fd2_tb                      matlab.ui.control.NumericEditField
    end


    methods (Access = private)
    
        %--------------------------------------------------------------------------
        function uifigurepop(app)
            drawnow;
            app.BC_UI.Visible = 'off';
            app.BC_UI.Visible = 'on';
        end    
        
        function update_plot(app)
            
            x_limits = [app.x_min_tb.Value, app.x_max_tb.Value];
            y_limits = [app.y_min_tb.Value, app.y_max_tb.Value];
            px_size = app.px_size_tb.Value;
            f_num = app.f_num_tb.Value;
            fl = app.focal_length_tb.Value;
            do1 = app.fd1_sl.Value*1000;
            do2 = app.fd2_sl.Value*1000;
            
            y_axis_ticks = [y_limits(1):px_size:(px_size*y_limits(2)+px_size)];
            y_axis_labels = num2str(y_axis_ticks'/px_size);
            
            [S_range1, CoC1, CoC_max1] = blurCalc(f_num, fl, do1, x_limits*1000);          
            [S_range2, CoC2, CoC_max2] = blurCalc(f_num, fl, do2, x_limits*1000);
            
            
            hold(app.plot_ax, 'off');
            plot(app.plot_ax, S_range1/1000, CoC1,'.-b');
            hold(app.plot_ax, 'on');
            plot(app.plot_ax, S_range2/1000, CoC2,'.-g');
            
            xlim(app.plot_ax, x_limits);
            ylim(app.plot_ax, y_limits*px_size);
            
            yticks(app.plot_ax, y_axis_ticks);
            yticklabels(app.plot_ax, y_axis_labels);
            
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_plot(app);
        end

        % Value changed function: fd1_sl, fd2_sl
        function fd_slValueChanged(app, event)
            
            app.fd1_sl.Value = app.fd1_tb.Value;
            app.fd2_sl.Value = app.fd2_tb.Value;
            
            update_plot(app);
            
        end

        % Value changed function: x_max_tb, x_min_tb, y_max_tb, 
        % y_min_tb
        function plot_control_ValueChanged(app, event)
            update_plot(app);
            
        end

        % Value changed function: f_num_tb, focal_length_tb, 
        % px_size_tb
        function lens_param_ValueChanged(app, event)
            update_plot(app);
            
        end

        % Value changed function: fd1_tb
        function fd_tbValueChanged(app, event)
            app.fd1_tb.Value = app.fd1_sl.Value;
            app.fd2_tb.Value = app.fd2_sl.Value;
            
            update_plot(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BC_UI = uifigure;
            app.BC_UI.Name = 'UI Figure';
            app.plot_ax = uiaxes(app.BC_UI);
            xlabel(app.plot_ax, 'Range (m)')
            ylabel(app.plot_ax, 'Pixels')
            app.PlotControlPanel = uipanel(app.BC_UI);
            app.PlotControlPanel.Title = 'Plot Control';
            app.XmLabel = uilabel(app.PlotControlPanel);
            app.XmLabel.Text = 'X (m):';
            app.x_min_tb = uieditfield(app.PlotControlPanel, 'numeric');
            app.x_min_tb.Limits = [0 Inf];
            app.x_min_tb.ValueChangedFcn = createCallbackFcn(app, @plot_control_ValueChanged, true);
            app.EditField_2Label = uilabel(app.PlotControlPanel);
            app.EditField_2Label.Text = '';
            app.x_max_tb = uieditfield(app.PlotControlPanel, 'numeric');
            app.x_max_tb.Limits = [0 Inf];
            app.x_max_tb.ValueChangedFcn = createCallbackFcn(app, @plot_control_ValueChanged, true);
            app.x_max_tb.Value = 20;
            app.YpxLabel = uilabel(app.PlotControlPanel);
            app.YpxLabel.Text = 'Y (px):';
            app.y_min_tb = uieditfield(app.PlotControlPanel, 'numeric');
            app.y_min_tb.Limits = [0 Inf];
            app.y_min_tb.ValueChangedFcn = createCallbackFcn(app, @plot_control_ValueChanged, true);
            app.EditField_2Label_2 = uilabel(app.PlotControlPanel);
            app.EditField_2Label_2.Text = '';
            app.y_max_tb = uieditfield(app.PlotControlPanel, 'numeric');
            app.y_max_tb.Limits = [20 Inf];
            app.y_max_tb.ValueChangedFcn = createCallbackFcn(app, @plot_control_ValueChanged, true);
            app.y_max_tb.Value = 20;
            app.LensParametersPanel = uipanel(app.BC_UI);
            app.LensParametersPanel.Title = 'Lens Parameters';
            app.PixelSizemmEditFieldLabel = uilabel(app.LensParametersPanel);
            app.PixelSizemmEditFieldLabel.Text = 'Pixel Size (mm):';
            app.px_size_tb = uieditfield(app.LensParametersPanel, 'numeric');
            app.px_size_tb.Limits = [0 1];
            app.px_size_tb.ValueDisplayFormat = '%.4f';
            app.px_size_tb.ValueChangedFcn = createCallbackFcn(app, @lens_param_ValueChanged, true);
            app.px_size_tb.Value = 0.0014;
            app.fnumberLabel = uilabel(app.LensParametersPanel);
            app.fnumberLabel.Text = 'f-number:';
            app.f_num_tb = uieditfield(app.LensParametersPanel, 'numeric');
            app.f_num_tb.Limits = [0 Inf];
            app.f_num_tb.ValueDisplayFormat = '%.2f';
            app.f_num_tb.ValueChangedFcn = createCallbackFcn(app, @lens_param_ValueChanged, true);
            app.f_num_tb.Value = 3.7;
            app.FocalLengthmmLabel = uilabel(app.LensParametersPanel);
            app.FocalLengthmmLabel.Text = 'Focal Length (mm):';
            app.focal_length_tb = uieditfield(app.LensParametersPanel, 'numeric');
            app.focal_length_tb.Limits = [0 Inf];
            app.focal_length_tb.ValueChangedFcn = createCallbackFcn(app, @lens_param_ValueChanged, true);
            app.focal_length_tb.Value = 9.6;
            app.DepthofFieldPanel = uipanel(app.BC_UI);
            app.DepthofFieldPanel.Title = 'Depth of Field';
            app.DNearmEditFieldLabel = uilabel(app.DepthofFieldPanel);
            app.DNearmEditFieldLabel.Text = 'D Near (m):';
            app.d_near_tb = uieditfield(app.DepthofFieldPanel, 'numeric');
            app.DFarmEditFieldLabel = uilabel(app.DepthofFieldPanel);
            app.DFarmEditFieldLabel.Text = 'D Far (m):';
            app.d_far_tb = uieditfield(app.DepthofFieldPanel, 'numeric');
            app.DoFmEditFieldLabel = uilabel(app.DepthofFieldPanel);
            app.DoFmEditFieldLabel.Text = 'DoF (m):';
            app.dof_tb = uieditfield(app.DepthofFieldPanel, 'numeric');
            app.FocusDistance1mSliderLabel = uilabel(app.BC_UI);
            app.FocusDistance1mSliderLabel.Text = 'Focus Distance 1 (m):';
            app.fd1_sl = uislider(app.BC_UI);
            app.fd1_sl.Limits = [0 2000];
            app.fd1_sl.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '2000'};
            app.fd1_sl.ValueChangedFcn = createCallbackFcn(app, @fd_slValueChanged, true);
            app.fd1_sl.Value = 10;
            app.FocusDistance2mLabel = uilabel(app.BC_UI);
            app.FocusDistance2mLabel.Text = 'Focus Distance 2 (m):';
            app.fd2_sl = uislider(app.BC_UI);
            app.fd2_sl.Limits = [0 2000];
            app.fd2_sl.MajorTicks = [0 80 160 240 320 400 480 560 640 720 800 880 960 1040 1120 1200 1280 1360 1440 1520 1600 1680 1760 1840 1920 2000];
            app.fd2_sl.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '2000'};
            app.fd2_sl.ValueChangedFcn = createCallbackFcn(app, @fd_slValueChanged, true);
            app.fd2_sl.Value = 20;
            app.fd1_tb = uieditfield(app.BC_UI, 'numeric');
            app.fd1_tb.Limits = [0 Inf];
            app.fd1_tb.ValueChangedFcn = createCallbackFcn(app, @fd_tbValueChanged, true);
            app.fd1_tb.Value = 10;
            app.fd2_tb = uieditfield(app.BC_UI, 'numeric');
            app.fd2_tb.Limits = [0 Inf];
            app.fd2_tb.Value = 20;
        end
    end

    methods (Access = public)

        % Construct app
        function app = blur_calculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BC_UI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BC_UI)
        end
    end
end